classdef CapacitorStatics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        DielectricMaterialPCSDropDown   matlab.ui.control.DropDown
        DielectricMaterialPCSLabel      matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        LeakageCurrentpAEditField       matlab.ui.control.NumericEditField
        LeakageCurrentpAEditFieldLabel  matlab.ui.control.Label
        BreakdownVoltageEditField       matlab.ui.control.NumericEditField
        BreakdownVoltageEditFieldLabel  matlab.ui.control.Label
        ConductancepSEditField          matlab.ui.control.NumericEditField
        ConductancepSEditFieldLabel     matlab.ui.control.Label
        CapacitancenFEditField          matlab.ui.control.NumericEditField
        CapacitancenFEditFieldLabel     matlab.ui.control.Label
        GetValuesButton                 matlab.ui.control.Button
        OuterRadiusCSEditField          matlab.ui.control.NumericEditField
        OuterRadiusCSEditFieldLabel     matlab.ui.control.Label
        InnerRadiusCSEditField          matlab.ui.control.NumericEditField
        InnerRadiusCSEditFieldLabel     matlab.ui.control.Label
        HeightCEditField                matlab.ui.control.NumericEditField
        HeightCEditFieldLabel           matlab.ui.control.Label
        WidthPEditField                 matlab.ui.control.NumericEditField
        WidthPEditFieldLabel            matlab.ui.control.Label
        LengthPEditField                matlab.ui.control.NumericEditField
        LengthPEditFieldLabel           matlab.ui.control.Label
        VoltagePCSEditField             matlab.ui.control.NumericEditField
        VoltagePCSLabel                 matlab.ui.control.Label
        DistancePCSEditField            matlab.ui.control.NumericEditField
        DistancePCSEditFieldLabel       matlab.ui.control.Label
        CapacitorTypeButtonGroup        matlab.ui.container.ButtonGroup
        SphericalButton                 matlab.ui.control.RadioButton
        CylindricalButton               matlab.ui.control.RadioButton
        ParallelPlateButton             matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetValuesButton
        function GetValuesButtonPushed(app, event)
                %define variables
                material = app.DielectricMaterialPCSDropDown.Value;
                length = app.LengthPEditField.Value;
                width = app.WidthPEditField.Value;
                distance = app.DistancePCSEditField.Value;
                voltage = app.VoltagePCSEditField.Value;
                height = app.HeightCEditField.Value;
                inner_radius = app.InnerRadiusCSEditField.Value;
                outer_radius = app.OuterRadiusCSEditField.Value;
                e0 = 8.854e-12;
                %check variables
                if length < 0 || width < 0 || distance < 0 || height < 0 || inner_radius < 0 || outer_radius < 0
                    length = abs(length);
                    width = abs(width);
                    distance = abs(distance);
                    height = abs(height);
                    inner_radius = abs(inner_radius);
                    outer_radius = abs(outer_radius);
                    warning('One of the inputs contains a negative value. Please check these values. Program has automatically taken the absolute value of given inputs');
                end
                %define dielectric constants table
                names = ["Air", "Alumina", "Barium Titanate", "Glass", "Ice", "Mica", "Polyethylene", "Polystyrene", "Quartz (fused)", "Silicon", "Soil", "Teflon", "Water (distilled)"];
                for i = 1:13
                    if names(i) == material
                        index = i;
                    end
                end
                dielectric_constants = readtable('Dielectric_Table.xlsx');
                info = table2array(dielectric_constants(index,2:4));
                %define plots axis
                x = 0:distance/100:distance*2;
                w = 1:10000;
                %call each shape
                if app.ParallelPlateButton.Value
                    %set(app.LengthPEditField, 'Visible', 'on');
                    %check values
                    if length == 0 || width == 0|| voltage == 0 || distance == 0
                        error('Check Parallel Plate inputs. Necessary components are missing');
                    end
                    %calculate capacitance
                    app.CapacitancenFEditField.Value = e0*info(1)*length*width/distance*10.^9;
                    %calculate conductance
                    app.ConductancepSEditField.Value = info(3)*length*width/distance*10.^12;
                    %calcualte leakage current
                    app.LeakageCurrentpAEditField.Value = info(3)*voltage*length*width/distance*10.^12;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*info(2);
                    %plot info
                    plot(app.UIAxes, x, x*voltage, '-r');
                    axis(app.UIAxes, 'tight');
                    plot(app.UIAxes2, w, 10*log(abs(1./(1j.*w.*e0.*info(1).*length.*width./distance))));
                    plot(app.UIAxes3, w, rad2deg(angle(1./(1j.*w.*e0.*info(1).*length.*width./distance))));
                    axis(app.UIAxes3, 'tight');
                end
                if app.CylindricalButton.Value
                    %check values
                    if height == 0 || inner_radius == 0 || voltage == 0|| distance == 0 || outer_radius == 0
                        error('Check Cylindrical inputs. Necessary components are missing');
                    end
                    % check radii and swap
                    if inner_radius > outer_radius
                        hold = inner_radius;
                        inner_radius = outer_radius;
                        outer_radius = hold;
                        warning('Inner radius is bigger than outer radius. Program has swapped values automatically');
                    end
                    if inner_radius == outer_radius
                        error('Radii are equal. Please change one value')
                    end
                    %calculate capacitance
                    app.CapacitancenFEditField.Value = 2*pi*info(1)*e0*height/(log(outer_radius/inner_radius))*10.^9;
                    %calculate conductance
                    app.ConductancepSEditField.Value = info(3)*height/(log(outer_radius/inner_radius))*10.^12;
                    %calcualte leakage current
                    app.LeakageCurrentpAEditField.Value = info(3)*voltage*height/(log(outer_radius/inner_radius))*10.^12;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*info(2);
                    %plot info
                    plot(app.UIAxes, x, x*voltage, '-r');
                    axis(app.UIAxes, 'tight');
                    plot(app.UIAxes2, w, 10*log(abs(1./(1j.*w.*2*pi*info(1)*e0*height/(log(outer_radius/inner_radius))))));
                    plot(app.UIAxes3, w, rad2deg(angle(1./(1j.*w.*2*pi*info(1)*e0*height/(log(outer_radius/inner_radius))))));
                    axis(app.UIAxes3, 'tight');
                end
                if app.SphericalButton.Value
                    %check values
                    if inner_radius == 0 || voltage == 0|| distance == 0 || outer_radius == 0
                        error('Check Spherical inputs. Necessary components are missing');
                    end
                    % check radii and swap
                    if inner_radius > outer_radius
                        hold = inner_radius;
                        inner_radius = outer_radius;
                        outer_radius = hold;
                        warning('Inner radius is bigger than outer radius. Program has swapped values automatically');
                    end
                    if inner_radius == outer_radius
                        error('Radii are equal. Please change one value')
                    end
                    %calculate capacitance
                    app.CapacitancenFEditField.Value = info(1)*4*pi*e0/(1/inner_radius - 1/outer_radius)*10.^9;
                    %calculate conductance
                    app.ConductancepSEditField.Value = info(3)*pi*e0/(1/inner_radius - 1/outer_radius)*10.^12;
                    %calcualte leakage current
                    app.LeakageCurrentpAEditField.Value = info(3)*voltage*pi*e0/(1/inner_radius - 1/outer_radius)*10.^12;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*info(2);
                    %plot info
                    plot(app.UIAxes, x/1000, x*voltage/1000, '-r');
                    axis(app.UIAxes, 'tight');
                    plot(app.UIAxes2, w, 10*log(abs(1./(1j.*w.*info(1)*4*pi*e0/(1/inner_radius - 1/outer_radius)))));
                    plot(app.UIAxes3, w, rad2deg(angle(1./(1j.*w.*info(1)*4*pi*e0/(1/inner_radius - 1/outer_radius)))));
                    axis(app.UIAxes3, 'tight');
                end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'E-Field vs Distance')
            xlabel(app.UIAxes, 'Distance (mm)')
            ylabel(app.UIAxes, 'E-Field (V/mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Bode Plot (Mag)')
            xlabel(app.UIAxes2, 'w (rad/s)')
            ylabel(app.UIAxes2, 'Magniude (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XScale = 'log';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Bode Plot (Phase)')
            xlabel(app.UIAxes3, 'w (rad/s)')
            ylabel(app.UIAxes3, 'phase (degrees)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XScale = 'log';
            app.CapacitorTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CapacitorTypeButtonGroup.Title = 'Capacitor Type';
            app.ParallelPlateButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.ParallelPlateButton.Text = 'Parallel Plate';
            app.ParallelPlateButton.Value = true;
            app.CylindricalButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.CylindricalButton.Text = 'Cylindrical';
            app.SphericalButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.SphericalButton.Text = 'Spherical';
            app.DistancePCSEditFieldLabel = uilabel(app.UIFigure);
            app.DistancePCSEditFieldLabel.Text = 'Distance (P, C, S)';
            app.DistancePCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltagePCSLabel = uilabel(app.UIFigure);
            app.VoltagePCSLabel.Text = 'Voltage (P, C, S)';
            app.VoltagePCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthPEditFieldLabel = uilabel(app.UIFigure);
            app.LengthPEditFieldLabel.Text = 'Length (P) ';
            app.LengthPEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthPEditFieldLabel = uilabel(app.UIFigure);
            app.WidthPEditFieldLabel.Text = 'Width (P)';
            app.WidthPEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightCEditFieldLabel = uilabel(app.UIFigure);
            app.HeightCEditFieldLabel.Text = 'Height (C)';
            app.HeightCEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiusCSEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiusCSEditFieldLabel.Text = 'Inner Radius (C, S)';
            app.InnerRadiusCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiusCSEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiusCSEditFieldLabel.Text = 'Outer Radius (C, S)';
            app.OuterRadiusCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.GetValuesButton = uibutton(app.UIFigure, 'push');
            app.GetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @GetValuesButtonPushed, true);
            app.GetValuesButton.Text = 'Get Values';
            app.CapacitancenFEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitancenFEditFieldLabel.Text = 'Capacitance (nF)';
            app.CapacitancenFEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductancepSEditFieldLabel = uilabel(app.UIFigure);
            app.ConductancepSEditFieldLabel.Text = 'Conductance (pS)';
            app.ConductancepSEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakdownVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.BreakdownVoltageEditFieldLabel.Text = 'Breakdown Voltage';
            app.BreakdownVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeakageCurrentpAEditFieldLabel = uilabel(app.UIFigure);
            app.LeakageCurrentpAEditFieldLabel.Text = 'Leakage Current (pA)';
            app.LeakageCurrentpAEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.DielectricMaterialPCSLabel = uilabel(app.UIFigure);
            app.DielectricMaterialPCSLabel.Text = 'Dielectric Material (P, C, S)';
            app.DielectricMaterialPCSDropDown = uidropdown(app.UIFigure);
            app.DielectricMaterialPCSDropDown.Items = {'Air', 'Alumina', 'Barium Titanate', 'Glass', 'Ice', 'Mica', 'Polyethylene', 'Polystyrene', 'Quartz (fused)', 'Silicon', 'Soil', 'Teflon', 'Water (distilled)', 'Seawater'};
            app.DielectricMaterialPCSDropDown.Value = 'Air';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Units: Voltage (V), Distance (m), Length (m), Width (m), Height (m), Inner Radius (m), Outer Radius(m)'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Note: Conductivity for alumina and barium titanate assumed to be 0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CapacitorStatics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end